classdef speaker_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        GrabarButton   matlab.ui.control.Button
        RECLampLabel   matlab.ui.control.Label
        RECLamp        matlab.ui.control.Lamp
        UIAxes         matlab.ui.control.UIAxes
        ProbsCat1      matlab.ui.control.Label
        ProbsCat2      matlab.ui.control.Label
        ProbsCat3      matlab.ui.control.Label
        PredictResult  matlab.ui.control.Label
        InfoLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        beta_coeffs % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.InfoLabel.Text = "Espere. Inicializando Modelo de Clasificacion"
            app.beta_coeffs = fit_logistic_regression();
            app.InfoLabel.Text = "Modelo Listo"
            pause(1.5);
            app.InfoLabel.Text = "Diga la palabra clave: Señales"
            app.GrabarButton.Enable = true
        end

        % Button pushed function: GrabarButton
        function GrabarButtonPushed(app, event)
            app.GrabarButton.Enable = false
            app.RECLamp.Color = 'green';
            
            Fs = 48000;
            recObj = audiorecorder(Fs,8,1);
            recordblocking(recObj, 1.5);
            x = getaudiodata(recObj);
            x = x./max(x);
            coeffs = mfcc(x, Fs, "LogEnergy", "Ignore", "WindowLength", Fs*0.04, 'NumCoeffs', 35);
            
            imagesc(app.UIAxes,coeffs(:,2:end)');
            colorbar(app.UIAxes);
            title(app.UIAxes,"Coeficientes Mel Cepstrum");
            ylabel(app.UIAxes,"Indice Coeficientes");
            xlabel(app.UIAxes,"Features");
            xlim(app.UIAxes, [1,size(coeffs, 1)]);
            ylim(app.UIAxes, [1,34.5]);
            
            probs = mean(mnrval(app.beta_coeffs, coeffs));
            app.ProbsCat1.Text = strcat("Probabilidad - Santiago: ", num2str(probs(1)));
            app.ProbsCat2.Text = strcat("Probabilidad - Michael: ", num2str(probs(2)));
            app.ProbsCat3.Text = strcat("Probabilidad - Desconocido: ", num2str(probs(3)))
            
            [m, category] = max(probs);
            if category == 1
                app.PredictResult.Text = "Voz Reconocida: Santiago"
            elseif category == 2
                app.PredictResult.Text = "Voz Reconocida: Michael"
            else
                app.PredictResult.Text = "Voz desconocida: Usuario desconocido"
            end
            
            
            app.GrabarButton.Enable = true;
            app.RECLamp.Color = 'red';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GrabarButton = uibutton(app.UIFigure, 'push');
            app.GrabarButton.ButtonPushedFcn = createCallbackFcn(app, @GrabarButtonPushed, true);
            app.GrabarButton.Enable = 'off';
            app.GrabarButton.Text = 'Grabar';
            app.RECLampLabel = uilabel(app.UIFigure);
            app.RECLampLabel.Text = 'REC';
            app.RECLamp = uilamp(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1];
            app.ProbsCat1 = uilabel(app.UIFigure);
            app.ProbsCat1.Text = 'Probabilidad - Santiago';
            app.ProbsCat2 = uilabel(app.UIFigure);
            app.ProbsCat2.Text = 'Probabilidad - Michael';
            app.ProbsCat3 = uilabel(app.UIFigure);
            app.ProbsCat3.Text = 'Probabilida - Otros';
            app.PredictResult = uilabel(app.UIFigure);
            app.PredictResult.Text = {'Resultado'; ''};
            app.InfoLabel = uilabel(app.UIFigure);
            app.InfoLabel.Text = {'Diga la palabra clave: Señales'; ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = speaker_gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end